classdef FSM_free_forced_convection_full_functionality < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        INPUTSPanel                  matlab.ui.container.Panel
        SetairspeedPanel             matlab.ui.container.Panel
        Button                       matlab.ui.control.Button
        airspeedmsEditFieldLabel     matlab.ui.control.Label
        airspeedmsEditField          matlab.ui.control.NumericEditField
        SetTempraturePanel           matlab.ui.container.Panel
        Button_2                     matlab.ui.control.Button
        TempraturecEditFieldLabel    matlab.ui.control.Label
        TempraturecEditField         matlab.ui.control.NumericEditField
        StartButton                  matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        SensorReadingsPanel          matlab.ui.container.Panel
        Button_3                     matlab.ui.control.Button
        probeTemperatureCLabel       matlab.ui.control.Label
        probeTemperatureCEditField   matlab.ui.control.EditField
        HeaterTemperatureCEditFieldLabel  matlab.ui.control.Label
        HeaterTemperatureCEditField  matlab.ui.control.EditField
        AirSpeedmsEditFieldLabel     matlab.ui.control.Label
        AirSpeedmsEditField          matlab.ui.control.EditField
    end


    properties (Access = private)
        state; % the finite state machine's state
        airspeed_set_point; % set point to be send to arduino to control air speed for
        Temprature_set_point; % et point to be send to arduino to control heater for
        connection; % connection object for serial connection with arduino
        test=0;
    end

    methods (Access = private)
    
        function  FSM_GUI(app)
             while(1)
                 %% connection_checking-----------------------------------
                    if (app.state=="connection_checking")
                        app.Label.Text=app.state;
                        app.state
                        %     1-creat connection to arduino 
                        %     2-sends 111.1 chars to the arduino and waits for "RighT" string back
                        %     3-if step 2 completed successfully it sets the FSM state to "waiting_start"
                        delete(instrfindall);
                        com_name = find_arduino_com_windowsOS();
                        app.connection = serial(com_name,'BaudRate',115200);
                        fopen(app.connection);
                        pause(1) % withouth this the serial print dont have enough time to reach arduino
                
                        
                        %send a message to make sure the arduino connected has the FSM code
                        %on it
                        fprintf(app.connection,'%s',"111.1");
                        % wait till there is data in the serial input buffer
                        while (app.connection.BytesAvailable ==0)
                        end
                        
                        %read the data
                        new_reading="";
                        if (app.connection.BytesAvailable > 0)
                            new_reading=(fscanf(app.connection));
                            if strtrim(new_reading)=="RighT"
                                app.state="waiting_start";
                            end
                        end
                    end
                 %% end of connection_checking-----------------------------------
                 
                 %% waiting_start state------------------------------------------
                    if (app.state=="waiting_start")
                        app.state;
                        app.Label.Text=app.state;
                        pause(.0000001)
                        app.airspeed_set_point=app.airspeedmsEditField.Value; % set point to be send to arduino to control air speed for
                        pause(0.5);
                        app.Temprature_set_point=app.TempraturecEditField.Value; % et point to be send to arduino to control heater for
                        pause(0.5);
                    end
                 %% end of waiting_start state------------------------------------------

                 %%sending_variables state-----------------------------------------------
                    if (app.state=="sending_variables")
                        app.Label.Text=app.state;
                        app.state;
                        while(get(app.connection,'TransferStatus')~="idle")end
                        fprintf(app.connection,'%s',"222.2",'async');
                        pause(.01);
                        
                        while(get(app.connection,'TransferStatus')~="idle")end
                        fprintf(app.connection,'%s',"*",'async');
                        pause(.01);
                        
                        while(get(app.connection,'TransferStatus')~="idle")end
                        fprintf(app.connection,'%s',num2str(app.Temprature_set_point),'async');
                        pause(.01);
                        
                        while(get(app.connection,'TransferStatus')~="idle")end
                        fprintf(app.connection,'%s',"*",'async');
                        pause(.01);
                        
                        while(get(app.connection,'TransferStatus')~="idle")end
                        fprintf(app.connection,'%s',num2str(app.airspeed_set_point),'async');
                        pause(.01);
                        app.state="graphing";
                        pause(1);
                    end
                 
                 %% end of sending_variables---------------------------------------------
                 
                 %%graphing state-----------------------------------------------
                    if (app.state=="graphing")
                        app.Label.Text=app.state;
                        pause(.5);

%                         temprature_Array=[];
%                         airspeed_Array=[];
                   
                 
                        while (app.connection.BytesAvailable ==0)
                
                        end
                
                
                        new_reading="";
                
                        if (app.connection.BytesAvailable > 0)
                           
                            while strtrim(new_reading) ~= "e"
                                new_reading=(fscanf(app.connection));
                                erase(new_reading,newline);
                                if (strtrim(new_reading)=="s")
                                    %-----------------------%
                                     new_reading=(fscanf(app.connection));
                                     probe_temprature_point=str2num(new_reading)
                                     %-----------------------%
                                     new_reading=(fscanf(app.connection));
                                     heater_temprature_point=str2num(new_reading);
                                     
%                                      temprature_Array =  [temprature_Array temprature_point(1)];
                                     %-----------------------%
                                     new_reading=(fscanf(app.connection));
                                     airspeed_point=str2num(new_reading);
%                                      airspeed_Array =  [airspeed_Array airspeed_point(1)];
                                     %-----------------------%

                                     %-----------------------%
                                     if(size(airspeed_point,1)==1 && size(heater_temprature_point,1)==1 && heater_temprature_point~=0 && size(probe_temprature_point,1)==1 && probe_temprature_point~=0)
                                         app.AirSpeedmsEditField.Value=num2str(airspeed_point(1));
                                         app.HeaterTemperatureCEditField.Value=num2str(heater_temprature_point);
                                         app.probeTemperatureCEditField.Value=num2str( probe_temprature_point);
         
                                         pause(0.0001);
                                     end
                                end
                
                            end
                        end
                    end
                 %% end of graphing---------------------------------------------
                 
             end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.state="connection_checking";
            app.airspeed_set_point = app.airspeedmsEditField.Value;
            app.Temprature_set_point = app.TempraturecEditField.Value;
            FSM_GUI(app);
            pause(.2);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

               if (app.state=="graphing")
                        fprintf(app.connection,'%s',"*145*"); 
                        app.state="waiting_start";
%                          while(get(app.connection,'TransferStatus')~="idle")
%                                 get(app.connection,'TransferStatus')
%                          end
                        FSM_GUI(app);
                end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

                        app.airspeed_set_point = app.airspeedmsEditField.Value;
                        app.Temprature_set_point = app.TempraturecEditField.Value;
                        app.state="sending_variables";
                        FSM_GUI(app);
                        pause(.2);
  
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.INPUTSPanel = uipanel(app.UIFigure);
            app.INPUTSPanel.Title = 'INPUTS';
            app.SetairspeedPanel = uipanel(app.INPUTSPanel);
            app.SetairspeedPanel.Title = 'Set air speed ';
            app.Button = uibutton(app.SetairspeedPanel, 'push');
            app.Button.Icon = 'fan_image.jpg';
            app.Button.Text = '';
            app.airspeedmsEditFieldLabel = uilabel(app.SetairspeedPanel);
            app.airspeedmsEditFieldLabel.Text = 'air speed (m/s) : ';
            app.airspeedmsEditField = uieditfield(app.SetairspeedPanel, 'numeric');
            app.airspeedmsEditField.Value = 5;
            app.SetTempraturePanel = uipanel(app.INPUTSPanel);
            app.SetTempraturePanel.Title = 'Set Temprature';
            app.Button_2 = uibutton(app.SetTempraturePanel, 'push');
            app.Button_2.Icon = 'thermometer-vector-icon.jpg';
            app.Button_2.Text = '';
            app.TempraturecEditFieldLabel = uilabel(app.SetTempraturePanel);
            app.TempraturecEditFieldLabel.Text = 'Temprature (c):';
            app.TempraturecEditField = uieditfield(app.SetTempraturePanel, 'numeric');
            app.TempraturecEditField.Value = 40;
            app.StartButton = uibutton(app.INPUTSPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.INPUTSPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Label = uilabel(app.INPUTSPanel);
            app.Label.Text = '';
            app.SensorReadingsPanel = uipanel(app.UIFigure);
            app.SensorReadingsPanel.Title = 'Sensor Readings';
            app.Button_3 = uibutton(app.SensorReadingsPanel, 'push');
            app.Button_3.Icon = 'ffc3.png';
            app.Button_3.Text = '';
            app.probeTemperatureCLabel = uilabel(app.SensorReadingsPanel);
            app.probeTemperatureCLabel.Text = {'probe '; 'Temperature (C):'};
            app.probeTemperatureCEditField = uieditfield(app.SensorReadingsPanel, 'text');
            app.probeTemperatureCEditField.Editable = 'off';
            app.HeaterTemperatureCEditFieldLabel = uilabel(app.SensorReadingsPanel);
            app.HeaterTemperatureCEditFieldLabel.Text = {'Heater '; 'Temperature (C):'};
            app.HeaterTemperatureCEditField = uieditfield(app.SensorReadingsPanel, 'text');
            app.HeaterTemperatureCEditField.Editable = 'off';
            app.AirSpeedmsEditFieldLabel = uilabel(app.SensorReadingsPanel);
            app.AirSpeedmsEditFieldLabel.Text = {'Air'; 'Speed (m/s):'};
            app.AirSpeedmsEditField = uieditfield(app.SensorReadingsPanel, 'text');
            app.AirSpeedmsEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FSM_free_forced_convection_full_functionality

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end